classdef AAPTS_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        ArduinoTestTab      matlab.ui.container.Tab
        StatusLabel         matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
        DistanceReadLabel   matlab.ui.control.Label
        CarStatisticsLabel  matlab.ui.control.Label
        Lamp                matlab.ui.control.Lamp
        Label               matlab.ui.control.Label
        CarParkingSlotsTab  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        ultrasonicObj % Description
        a % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %clear all;
            app.a = arduino('COM6', 'Uno', 'Libraries', 'Ultrasonic');

            app.ultrasonicObj = ultrasonic(app.a,'D6','D7')

        
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            while true
                distance = 100.0*readDistance(app.ultrasonicObj)
                app.DistanceReadLabel.Text = "Distance: "+string(distance);
                if distance <= 5
                    app.Lamp.Color = "red";
                    app.StatusLabel.Text = "Status: Occupied";
                else
                    app.Lamp.Color = "green";
                    app.StatusLabel.Text = "Status: Available";
                end
                drawnow
                pause(1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ArduinoTestTab = uitab(app.TabGroup);
            app.ArduinoTestTab.Title = 'Arduino Test';
            app.Label = uilabel(app.ArduinoTestTab);
            app.Label.Text = '';
            app.Lamp = uilamp(app.ArduinoTestTab);
            app.CarStatisticsLabel = uilabel(app.ArduinoTestTab);
            app.CarStatisticsLabel.Text = 'Car Statistics';
            app.DistanceReadLabel = uilabel(app.ArduinoTestTab);
            app.DistanceReadLabel.Text = 'Distance Read: ';
            app.StartButton = uibutton(app.ArduinoTestTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StatusLabel = uilabel(app.ArduinoTestTab);
            app.StatusLabel.Text = 'Status: ';
            app.CarParkingSlotsTab = uitab(app.TabGroup);
            app.CarParkingSlotsTab.Title = 'Car Parking Slots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AAPTS_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end